classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GesturesButton           matlab.ui.control.Button
        MeanSampledButton        matlab.ui.control.Button
        DigitizedDataButton      matlab.ui.control.Button
        BaselineCorrectedButton  matlab.ui.control.Button
        FrequencyFilteredButton  matlab.ui.control.Button
        FrequencyRawButton       matlab.ui.control.Button
        PlotFilteredDataButton   matlab.ui.control.Button
        SignalProcessButton      matlab.ui.control.Button
        DataEditField            matlab.ui.control.EditField
        DataLabel                matlab.ui.control.Label
        PlotRawDataButton        matlab.ui.control.Button
        RecordButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data % Description
        time_f % Description
        filtered_emg % Description
        fq % Description
        X_norm % Description
        fq_filter % Description
        X_fnorm % Description
        corrected_emg % Description
        digitized_emg % Description
        mean_emg_array % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            % Define ESP32 IP address and port
            ESP32_IP = '192.168.26.45'; % Change to the IP address of your ESP32
            ESP32_Port = 90; % Change to the port number you specified in the ESP32 code
%             app.DataEditField.Value = "Receiving";
            % Create a TCP/IP object
            tcpipClient = tcpip(ESP32_IP, ESP32_Port);
            set(tcpipClient, 'InputBufferSize', 2048);

            % Open the TCP/IP connection
            fopen(tcpipClient);

            app.data = [];

            for i=1:1000    % Read data from ESP32 over WiFi
                potValue = str2double(fgetl(tcpipClient));
                app.data = [app.data; potValue];
            end
            app.DataEditField.Value="Received";
            fclose(tcpipClient);
            delete(tcpipClient);
            clear tcpipClient;
        end

        % Button pushed function: PlotRawDataButton
        function PlotRawDataButtonPushed(app, event)
            app.time_f = linspace(0,9,length(app.data));
            plot(app.UIAxes, app.time_f, app.data);
            app.DataEditField.Value = "";
            xlabel(app.UIAxes,"Time");
            ylabel(app.UIAxes,"Amplitude");
            title(app.UIAxes, "Sampled Data")
        end

        % Button pushed function: SignalProcessButton
        function SignalProcessButtonPushed(app, event)
            timeDiffs = diff(app.time_f);
            averageTimeDiff = mean(timeDiffs);
            fs = 1/averageTimeDiff;

            emg_signal=app.data;
            hpf = fir1(5, 10/(fs/2));
            app.filtered_emg = filter(hpf,1,emg_signal);


            N = length(emg_signal);
            app.fq = (0:N - 1) * fs / N;
            X = fftshift(fft(emg_signal));
            app.X_norm = (1 / N) * X;

            N = length(app.filtered_emg);
            app.fq_filter = (0:N - 1) * fs / N;
            X = fftshift(fft(app.filtered_emg));
            app.X_fnorm = (1 / N) * X;

            baseline_offset = mean(app.filtered_emg); % Use first 100 samples (adjust window if needed)
            app.corrected_emg = app.filtered_emg - baseline_offset;

            threshold = 0.001;
            app.digitized_emg=[];

            for i = 1:length(emg_signal)
                if (app.corrected_emg(i)>threshold)
                    app.digitized_emg(i) = 3.3;
                else
                    app.digitized_emg(i) = 0;
                end
            end
        end

        % Button pushed function: PlotFilteredDataButton
        function PlotFilteredDataButtonPushed(app, event)
            plot(app.UIAxes, app.time_f, app.filtered_emg);
            xlabel(app.UIAxes,"Time");
            ylabel(app.UIAxes,"Amplitude");
            title(app.UIAxes, "Filtered Sample Data")
        end

        % Button pushed function: FrequencyRawButton
        function FrequencyRawButtonPushed(app, event)
            plot(app.UIAxes, app.fq, abs(app.X_norm));
            xlabel(app.UIAxes,"Frequency");
            ylabel(app.UIAxes,"Magnitude");
            title(app.UIAxes, "Frequency Response of Sampled Data")
        end

        % Button pushed function: FrequencyFilteredButton
        function FrequencyFilteredButtonPushed(app, event)
            plot(app.UIAxes, app.fq_filter, abs(app.X_fnorm));
            xlabel(app.UIAxes,"Frequency");
            ylabel(app.UIAxes,"Magnitude");
            title(app.UIAxes, "Frequency Response of Filtered Data")
        end

        % Button pushed function: BaselineCorrectedButton
        function BaselineCorrectedButtonPushed(app, event)
            plot(app.UIAxes, app.time_f, app.corrected_emg);
            xlabel(app.UIAxes,"Time");
            ylabel(app.UIAxes,"Amplitude");
            title(app.UIAxes, "Base Corrected Sample Data")
        end

        % Button pushed function: DigitizedDataButton
        function DigitizedDataButtonPushed(app, event)
            plot(app.UIAxes, app.time_f, app.digitized_emg);
            xlabel(app.UIAxes,"Time");
            ylabel(app.UIAxes,"Amplitude");
            title(app.UIAxes, "Digitized Sample Data")
        end

        % Button pushed function: MeanSampledButton
        function MeanSampledButtonPushed(app, event)
            tolerance = 0.05;
            app.mean_emg_array =[];
            last_index = 1;
            time= 1;
            for i = 1:length(app.time_f)
                if (abs(app.time_f(i)- time) < tolerance)
                    %        disp("error")
                    %        disp(abs(t(i)- time))
                    mean_emg = mean(app.digitized_emg(last_index:i));
                    app.mean_emg_array = [app.mean_emg_array; mean_emg];
                    time=time+1;
                    last_index=i;
                end
            end
        end

        % Button pushed function: GesturesButton
        function GesturesButtonPushed(app, event)
            plot(app.UIAxes, app.mean_emg_array);
            xlabel(app.UIAxes,"Mean Values");
            ylabel(app.UIAxes,"Amplitude");
            title(app.UIAxes, "Mean Values of Sampled Data")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlotRawDataButton = uibutton(app.UIFigure, 'push');
            app.PlotRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawDataButtonPushed, true);
            app.PlotRawDataButton.Text = 'Plot Raw Data';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Text = 'Data:';
            app.DataEditField = uieditfield(app.UIFigure, 'text');
            app.SignalProcessButton = uibutton(app.UIFigure, 'push');
            app.SignalProcessButton.ButtonPushedFcn = createCallbackFcn(app, @SignalProcessButtonPushed, true);
            app.SignalProcessButton.Text = 'Signal Process';
            app.PlotFilteredDataButton = uibutton(app.UIFigure, 'push');
            app.PlotFilteredDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFilteredDataButtonPushed, true);
            app.PlotFilteredDataButton.Text = 'Plot Filtered Data';
            app.FrequencyRawButton = uibutton(app.UIFigure, 'push');
            app.FrequencyRawButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyRawButtonPushed, true);
            app.FrequencyRawButton.Text = 'Frequency Raw';
            app.FrequencyFilteredButton = uibutton(app.UIFigure, 'push');
            app.FrequencyFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyFilteredButtonPushed, true);
            app.FrequencyFilteredButton.Text = 'Frequency Filtered';
            app.BaselineCorrectedButton = uibutton(app.UIFigure, 'push');
            app.BaselineCorrectedButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineCorrectedButtonPushed, true);
            app.BaselineCorrectedButton.Text = 'Baseline Corrected';
            app.DigitizedDataButton = uibutton(app.UIFigure, 'push');
            app.DigitizedDataButton.ButtonPushedFcn = createCallbackFcn(app, @DigitizedDataButtonPushed, true);
            app.DigitizedDataButton.Text = 'Digitized Data';
            app.MeanSampledButton = uibutton(app.UIFigure, 'push');
            app.MeanSampledButton.ButtonPushedFcn = createCallbackFcn(app, @MeanSampledButtonPushed, true);
            app.MeanSampledButton.Text = 'Mean Sampled';
            app.GesturesButton = uibutton(app.UIFigure, 'push');
            app.GesturesButton.ButtonPushedFcn = createCallbackFcn(app, @GesturesButtonPushed, true);
            app.GesturesButton.Text = 'Gestures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end